classdef blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        HitButton    matlab.ui.control.Button
        StandButton  matlab.ui.control.Button
        DeckBack     matlab.ui.control.Image
        DEALButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        deck % Description
        xpos = 310;
    end
    
    methods (Access = public)
        
        function cardImage = cardDeal(app, deck, xpos, number)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos 26 90 112];
            if app.deck.p1Hand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.p1Hand.cards(number).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(number).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
    
    
        function cardImage = newCard(app, deck, xpos)
            cardImage = uiimage(app.UIFigure);
            cardImage.Position = [xpos 26 90 112];
            if app.deck.p1Hand.cards(end).privacy == 1
                cardImage.ImageSource = app.deck.p1Hand.cards(end).imageName;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            elseif app.deck.p1Hand.cards(end).privacy == 0
                cardImage.ImageSource = app.deck.p1Hand.cards(end).back;
                % Show the figure after all components are created
                app.UIFigure.Visible = 'on';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            import pkg.Deck.*
            import pkg.Card.*
            import pkg.Hand.*
            import pkg.DiscardPile.*
        end

        % Button pushed function: DEALButton
        function dealButton(app, event)
            app.deck = Deck;
            app.DEALButton.Visible = 'off';
            app.DEALButton.Enable = 'off';
            app.deck.p1Hand.cards(1).imageApp = cardDeal(app, app.deck, 347, 1);
            app.deck.p1Hand.cards(2).imageApp = cardDeal(app, app.deck, 310, 2);
            app.HitButton.Enable = 'on';
            app.StandButton.Enable = 'on';

        end

        % Button pushed function: HitButton
        function hitMe(app, event)
            app.deck.p1Hand.cards = [app.deck.p1Hand.cards, app.deck.cards(1)];
            app.deck.p1Hand.cards(end).privacy = 1;
            app.deck.cards(1) = [];
            app.xpos = app.xpos - 37;
            app.deck.p1Hand.cards(end).imageApp = newCard(app, app.deck, app.xpos);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @hitMe, true);
            app.HitButton.Enable = 'off';
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.Enable = 'off';
            app.StandButton.Text = 'Stand';
            app.DeckBack = uiimage(app.UIFigure);
            app.DeckBack.ImageSource = 'back.jpg';
            app.DEALButton = uibutton(app.UIFigure, 'push');
            app.DEALButton.ButtonPushedFcn = createCallbackFcn(app, @dealButton, true);
            app.DEALButton.Text = 'DEAL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end